classdef solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SolverUIFigure               matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        InputsPanel                  matlab.ui.container.Panel
        MethodDropDownLabel          matlab.ui.control.Label
        MethodDropDown               matlab.ui.control.DropDown
        NumberofEquationsEditFieldLabel  matlab.ui.control.Label
        NumberofEquationsEditField   matlab.ui.control.NumericEditField
        EquationsTextAreaLabel       matlab.ui.control.Label
        EquationsTextArea            matlab.ui.control.TextArea
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel      matlab.ui.control.Label
        ToleranceEditField           matlab.ui.control.NumericEditField
        SolveButton                  matlab.ui.control.Button
        VariablesEditField           matlab.ui.control.EditField
        VariablesLabel               matlab.ui.control.Label
        InitialGuessesEditField      matlab.ui.control.EditField
        InitialGuessesLabel          matlab.ui.control.Label
        OutputsPanel                 matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
        ExcutionTimeEditFieldLabel   matlab.ui.control.Label
        ExcutionTimeEditField        matlab.ui.control.NumericEditField
        IterationsEditFieldLabel     matlab.ui.control.Label
        IterationsEditField          matlab.ui.control.NumericEditField
        ResultsTextAreaLabel         matlab.ui.control.Label
        ResultsTextArea              matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SolverUIFigure, 'center');
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if value == "Gauss-Seidel Method" || value == "All"
                app.InitialGuessesEditField.Enable = true;
                app.InitialGuessesLabel.Enable = true;
                app.MaxIterationsEditField.Enable = true;
                app.MaxIterationsEditFieldLabel.Enable = true;
                app.ToleranceEditField.Enable = true;
                app.ToleranceEditFieldLabel.Enable = true;
            else
                app.InitialGuessesEditField.Enable = false;
                app.InitialGuessesLabel.Enable = false;
                app.MaxIterationsEditField.Enable = false;
                app.MaxIterationsEditFieldLabel.Enable = false;
                app.ToleranceEditField.Enable = false;
                app.ToleranceEditFieldLabel.Enable = false;
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [error, n, method, symbols, equations, initialConditions, max_iter, epsilon, isIterative] = readfile();
            if error == 0
                app.MethodDropDown.Value = method;
                app.NumberofEquationsEditField.Value = n;
                app.VariablesEditField.Value = symbols;
                app.EquationsTextArea.Value = equations;
                if isIterative
                    app.InitialGuessesEditField.Value = initialConditions;
                    app.MaxIterationsEditField.Value = max_iter;
                    app.ToleranceEditField.Value = epsilon;
                end
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            method = app.MethodDropDown.Value;
            n = app.NumberofEquationsEditField.Value;
            variables = app.VariablesEditField.Value;
            equations = app.EquationsTextArea.Value;
            if method == "Gauss-Seidel Elimination"
                initialConditions = app.InitialGuessesEditField.Value;
                maxIterations = app.MaxIterationsEditField.Value;
                tolerance = app.ToleranceEditField.Value;
                isIterative = true;
            end
            [coeffsMatrix, resultsMatrix, variables, error] = parseInput(variables, equations, n);
            if error ~= 0
                % display error
            end
            isAll = false;
            switch (method)
                case "Guass Elimination"
                    tic;
                    [X isSingular] = gaussElimination(coeffsMatrix, resultsMatrix, n, tolerance);
                    app.ExcutionTimeEditField.Value = toc;
                case "Gauss-Jordan Elimination"
                    tic;
                    [X isSingular] = gaussJordanElimination(coeffsMatrix, resultsMatrix, n, tolerance);
                    app.ExcutionTimeEditField.Value = toc;
                case "LU Decomposition"
                    tic;
                    [X isSingular] = LUdecomposition(coeffsMatrix, resultsMatrix, n, tolerance);
                    app.ExcutionTimeEditField.Value = toc;
                case "Gauss-Seidel Method"
                    tic;
                    [X iterations, data, precision] = gaussSeidel(coeffsMatrix, resultsMatrix, initialConditions, n, maxIterations, tolerance);
                    app.ExcutionTimeEditField.Value = toc;
                case "All"
                    tic;
                    [Xgauss temp] = gaussElimination(coeffsMatrix, resultsMatrix, n, tolerance);
                    isSingular = temp;
                    [XgaussJordan temp] = gaussJordanElimination(coeffsMatrix, resultsMatrix, n, tolerance);
                    isSingular = isSingular && temp;
                    [XluDecomp temp] = LUdecomposition(coeffsMatrix, resultsMatrix, n, tolerance);
                    isSingular = isSingular && temp;
                    [XgaussSeidel iterations, data, precision] = gaussSeidel(coeffsMatrix, resultsMatrix, initialConditions, n, maxIterations, tolerance);
                    app.ExcutionTimeEditField.Value = toc;
                    isAll = true;
            end
            if ~isAll
                if ~isIterative
                    if ~isSigular
                        app.ResultsTextArea = formatAnswer(X, variables);
                    else
                        % display error
                    end
                else
                    app.ResultsTextArea = formatAnswer(X, variables);
                    app.IterationsEditField.Value = iterations;
%                     name = {};
%                     for i = 1 : n
%                         name = [name variables(i)];
%                     end
%                     app.UITable.ColumnName = name;
                end
            else
                app.ResultsTextArea = formatAllAnswers(Xgauss, XgaussJordan, XluDecomp, XgaussSeidel, variables);
            end
            % plot
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SolverUIFigure = uifigure('Visible', 'off');
            app.SolverUIFigure.Name = 'Systems of Linear Equations Solver';
            app.FileMenu = uimenu(app.SolverUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.InputsPanel = uipanel(app.SolverUIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.MethodDropDownLabel = uilabel(app.InputsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.InputsPanel);
            app.MethodDropDown.Items = {'Gauss Elimination', 'Gauss-Jordan Elimination', 'LU Decomposition', 'Gauss-Seidel Method', 'All'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Gauss Elimination';
            app.NumberofEquationsEditFieldLabel = uilabel(app.InputsPanel);
            app.NumberofEquationsEditFieldLabel.Text = 'Number of Equations';
            app.NumberofEquationsEditField = uieditfield(app.InputsPanel, 'numeric');
            app.NumberofEquationsEditField.Limits = [0 999];
            app.EquationsTextAreaLabel = uilabel(app.InputsPanel);
            app.EquationsTextAreaLabel.Text = 'Equations (One equation per line)';
            app.EquationsTextArea = uitextarea(app.InputsPanel);
            app.MaxIterationsEditFieldLabel = uilabel(app.InputsPanel);
            app.MaxIterationsEditFieldLabel.Enable = 'off';
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.InputsPanel, 'numeric');
            app.MaxIterationsEditField.Limits = [0 999];
            app.MaxIterationsEditField.Enable = 'off';
            app.MaxIterationsEditField.Value = 50;
            app.ToleranceEditFieldLabel = uilabel(app.InputsPanel);
            app.ToleranceEditFieldLabel.Enable = 'off';
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ToleranceEditField.Limits = [0 1];
            app.ToleranceEditField.Enable = 'off';
            app.ToleranceEditField.Value = 1e-05;
            app.SolveButton = uibutton(app.InputsPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.VariablesEditField = uieditfield(app.InputsPanel, 'text');
            app.VariablesLabel = uilabel(app.InputsPanel);
            app.VariablesLabel.Text = 'Variables (Enter space-separated values)';
            app.InitialGuessesEditField = uieditfield(app.InputsPanel, 'text');
            app.InitialGuessesEditField.Enable = 'off';
            app.InitialGuessesLabel = uilabel(app.InputsPanel);
            app.InitialGuessesLabel.Enable = 'off';
            app.InitialGuessesLabel.Text = 'Initial Guesses (Enter space-separated values)';
            app.OutputsPanel = uipanel(app.SolverUIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.UITable = uitable(app.OutputsPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UIAxes = uiaxes(app.OutputsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Iterations')
            ylabel(app.UIAxes, 'Root Values')
            app.ExcutionTimeEditFieldLabel = uilabel(app.OutputsPanel);
            app.ExcutionTimeEditFieldLabel.Text = 'Excution Time';
            app.ExcutionTimeEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.IterationsEditFieldLabel = uilabel(app.OutputsPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.ResultsTextAreaLabel = uilabel(app.OutputsPanel);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.OutputsPanel);
            % Show the figure after all components are created
            app.SolverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SolverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SolverUIFigure)
        end
    end
end